% description: Model mosaics of arbitrary size of cones and rods that are interconnected via gap-junctions. Simulate the photoresponse in directly illuminated photoreceptors and model spread of signal
% across gap-junctions. Copyright 2024-2025 Luca Della Santina.
classdef ConeRodModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeRodModelUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        BatchCustomSteps      matlab.ui.control.EditField
        BatchCustomRange      matlab.ui.control.CheckBox
        btnPreview            matlab.ui.control.Button
        BatchRunAvg           matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        SystemTab             matlab.ui.container.Tab
        tblParams             matlab.ui.control.Table
        RodsTab               matlab.ui.container.Tab
        tblParamsRods         matlab.ui.control.Table
        ConesTab              matlab.ui.container.Tab
        tblParamsCones        matlab.ui.control.Table
        BatchUpdate           matlab.ui.control.Button
        btnInfo               matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        BatchSteps            matlab.ui.control.NumericEditField
        StepsEditFieldLabel   matlab.ui.control.Label
        BatchTo               matlab.ui.control.NumericEditField
        ToEditFieldLabel      matlab.ui.control.Label
        BatchFrom             matlab.ui.control.NumericEditField
        FromEditFieldLabel    matlab.ui.control.Label
        BatchSetting          matlab.ui.control.DropDown
        BatchRun              matlab.ui.control.Button
        BatchList             matlab.ui.control.ListBox
        BatchAddRange         matlab.ui.control.Button
        BatchClear            matlab.ui.control.Button
        BatchRemove           matlab.ui.control.Button
        BatchAdd              matlab.ui.control.Button
        PlotButton            matlab.ui.control.Button
        SolveButton           matlab.ui.control.Button
        PnlPlots              matlab.ui.container.Panel
        ContextMenuPlots      matlab.ui.container.ContextMenu
        Menu3Dplot            matlab.ui.container.Menu
        MenuSavePlots         matlab.ui.container.Menu
    end

%% ConeRodModel- Model a network of interconnected cones and rod
%  Copyright (C) 2024-2025 Luca Della Santina
%
%  This file is part of ConeRodModel
%
%  ObjectFinder is free software: you can redistribute it and/or modify
%  it under the terms of the GNU General Public License as published by
%  the Free Software Foundation, either version 3 of the License, or
%  (at your option) any later version.
%
%  This program is distributed in the hope that it will be useful,
%  but WITHOUT ANY WARRANTY; without even the implied warranty of
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%  GNU General Public License for more details.
%
%  You should have received a copy of the GNU General Public License
%  along with this program.  If not, see <https://www.gnu.org/licenses/>.
%    
    properties (Access = private)
        Version = '4.4'
        Params = struct % Constants and parameters of the system
        ParamsRods = struct % Constants and parameters of the rods
        ParamsCones = struct % Constants and parameters of the cones
        Solution = struct % Solution of the model with current params
        Layout % Plotting area of the UI

        % Gating variables (set by initalizeModel, updated by myode)
        mKv = []; % Kv activation (cones)
        nKv = []; % Kv activation (rods)
        hKv = []; % Kv inactivation (cones)
        nKx = []; % Kx inactivation (rods)
        nh  = []; % Ih activation
        nCa = []; % Calcium activation (cones)
        mCa = []; % Calcium activation (rods)
        hCa = []; % Calcium inactivation
        dCai_dt = [];
        Cai = []; % Initial intracellular calcium concentration in mM
        C = []; % Capacitance matrix
        S_total = []; % Connectivity graph
    end
    
    methods (Static)
        function P = getDefaultParams
            % P is a struct contains all initialized parameters

            P = struct;
            P.FlashRstar = 5; % Light intensity of the flash in R*            
            P.RefCell = 1;  % Reference cone for plotting results
            P.ConeRows = 1; % Number of rows of Cones
            P.ConeCols = 1; % Number of columns of cones
            P.RodRows = 5;  % Number of rows of rods (per cone)
            P.RodCols = 6;  % Number of columns of rods (per cone)
            P.RodsIllNum = 30; % Number of rods illuminated (per cone)
            P.ConesIllNum = 0; % Number of cones illuminated
            P.RodsIllRandom = false; % 50 percent of rods randomly illuminated (per cone)
            P.ConesIllRandom = false; % 50 percent of rods randomly illuminated (per cone)
            P.Tmax = 1.8; % End Time of the simulation in second
            P.Tdelta = 0.00001; % Sampling interval is seconds
            P.Tdelay = 0.2; % Time delay of flash trigger in seconds
            P.CCjc = 40; % Direct cone/cone coupling conductance in nS, typical 40nS
            P.RRjc = 0; % Direct rod/rod coupling conductance in nS, typical 0ns
            P.RCjc = 300; %0.1; % rod/cone coupling conductance in nS, typical 300nS, max 1400nS (mouse)
            P.RCjcRandom = false; % Whether to randomize RCjc or keep constant
            P.RCjcRndMean = 1.2; % Mean of normal distribution to pick random values from , in nS
            P.RCjcRndSD = 0.4; % Standard deviation of normal distribution to pick random values from, in nS
            P.Rblocksjc = 0; % rod/rod coupling; connections between blocks of rods in nS, typical 0nS
        end

        function P = getDefaultParamsRods
            % P is a struct contains all initialized parameters

            P = struct;
            P.NoiseRodAmp = 1; % rod noise amplitude in pA;
            P.Rmr = 2.3;  % rod membrane resistance in Gohms
            P.Cmr = 10;     % rod membrane capacitance in pF
            P.Er = -42.8;   % rod resting membrane potential in mV
            P.volume_rod = (4/3) * pi * 7.8^3; % volume in um^3
            P.Idark_rod = -42; % Dark current in pA
            P.ECa_rod = 40; % Calcium reversal potential in mV
            P.EKv_rod = -74; % Kv reversal potential in mV
            P.EKx_rod = -74; % Kx reversal potential in mV
            P.Eh_rod = -32; % Ih reversal potential in mV
            P.EKCa_rod = -74; % KCa reversal potential in mV
            P.ECl_rod = -20; % Cl reversal potential in mV
            P.EL_rod = -74; % Leak reversal potential in mV            
            P.gCa_rod = 4; % Calcium conductance in nS
            P.gKv_rod = 10; % Kv conductance in nS
            P.gKx_rod = 1.04; % Kx conductance in nS
            P.gh_rod = 2.5; % Ih conductance in nS
            P.gKCa_rod = 5; % KCa conductance in nS
            P.gCl_rod = 1.3; % Cl conductance in nS
            P.gL_rod = 0.52; % Leak conductance in nS            
            P.K_KCa_rod = 0.00032; % KCa constant in mM
            P.K_Cl_rod = 0.0015; % Cl constant in mM
            P.Cainf_rod = 5e-5; % Steady-state calcium concentration in mM
            P.tau_Cai_rod = 20; % Time constant for calcium decay in ms                                        
        end

        function P = getDefaultParamsCones
            % P is a struct contains all initialized parameters

            P.NoiseConeAmp = 1; % cone noise amplitude in pA;
            P.Rmc = 2.4;  % cone membrane resistance in Gohms
            P.Cmc = 10;     % cone membrane capacitance in pF
            P.Ec = -41.0;   % cone resting membrane potential in mV 

            % Constants for cones (Kourennyi 2004)
            P.volume_cone = (4/3) * pi * 7.8^3; % volume in um^3
            P.Idark_cone = -20; % Dark current in pA
            P.ECa_cone = 40; % Calcium reversal potential in mV
            P.EKv_cone = -80; % Kv reversal potential in mV
            P.Eh_cone = -32.5; % Ih reversal potential in mV
            P.EKCa_cone = -80; % KCa reversal potential in mV
            P.ECl_cone = -45; % Cl reversal potential in
            P.EL_cone = -63; % Leak reversal potential in mV        
            P.gCa_cone = 4.92; % Calcium conductance in nS
            P.gKv_cone = 2; % Kv conductance in nS
            P.gh_cone = 3.5; % Ih conductance in nS
            P.gKCa_cone = 0.5; % KCa conductance in nS
            P.gCl_cone = 6.5; % Cl conductance in nS 
            P.gL_cone = 1; % Leak conductance in nS      
            % Intracellular calcium concentration (Liu & Kourennyi 2004)
            P.aoCa = 3.1; % Calcium activation constant (1/s)
            P.SCa = 5.7; % Calcium scaling factor in mV
            P.VhalfCa = -16.6; % Calcium half activation voltage in mV
            P.Cainf_cone = 0.0001; % Steady-state calcium concentration in mM        
            P.tau_Cai_cone = 20; % Time constant for calcium decay in ms
            
        end
                
        function D = getCurrentDateString
            % Returns the current date/time as a string, OS safe version
            % otherwise time includes ":" which is not allowred in
            % filennames on Windows OS

            D = char(datetime('now'));
            D = strrep(D, ':', '-');
        end
    end

    methods (Access = private)

        function P = getUserParams(app)
            CE = table2cell(app.tblParams.Data);
            P = cell2struct(CE(:,2),CE(:,1),1);
        end
                
        function P = getUserParamsRods(app)
            CE = table2cell(app.tblParamsRods.Data);
            P = cell2struct(CE(:,2),CE(:,1),1);
        end

        function P = getUserParamsCones(app)
            CE = table2cell(app.tblParamsCones.Data);
            P = cell2struct(CE(:,2),CE(:,1),1);
        end
        
        function [P, PRods, PCones] = initializeModel(app)
            
            % Get user-defined parameters
            P = app.getUserParams;
            PRods = app.getUserParamsRods;
            PCones = app.getUserParamsCones;

            nCones = P.ConeRows*P.ConeCols; % Total number of cones
            nRPC = P.RodRows*P.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors

            % IR, rod photocurrent (all rods treated as a group)
            % rods connected to cone 5 are elements #170-209 (or rods #160-199)
            % Itr is the vector containing all time steps

            % Determine which photoreceptor is illuminated (P.Illuminated = 1)
            % TODO: allow random cones to be illuminated as done for rods

            P.Illuminated= zeros(nPR,1); % 0=not illuminated, 1=illuminated
            for cone = 1:nCones
                IllumC = zeros(nCones,1); % Default is no illumination, just random noise
                if P.ConesIllRandom
                    idxs = randperm(length(IllumC)); % Randomize indeces of illuminated rods
                    idxs = idxs(1:P.ConesIllNum);
                else
                    idxs = 1:P.ConesIllNum; % Pick the first RodsIllNum rods
                end
                IllumC(idxs) = 1;
                P.Illuminated(1:nCones) = IllumC; % Store illumination status of each photoreceptor in P.Illuminated
            end
            for rod = nCones+1:nRPC:nPR
                IllumRPC = zeros(nRPC,1); % Default is no illumination, just random noise
                if P.RodsIllRandom
                    idxs = randperm(length(IllumRPC)); % Randomize indeces of illuminated rods
                    idxs = idxs(1:P.RodsIllNum);
                else
                    idxs = 1:P.RodsIllNum; % Pick the first RodsIllNum rods
                end
                IllumRPC(idxs) = 1;
                P.Illuminated(rod:rod+nRPC-1) = IllumRPC; % Store illumination status of each photoreceptor in P.Illuminated
            end

            % IR photocurrent for all rods (either illuminated + noise or just noise)
            % Itr is the vector containing all time steps
            P.Itr = linspace(0,P.Tmax,ceil(P.Tmax/P.Tdelta)); % from t=0 to t=1s, 1000 points
            P.IR = zeros(nPR-nCones,ceil(P.Tmax/P.Tdelta)); % reset to 0
            for rod = nCones+1:nPR
                P.IR(rod,:) = app.calcIphoto_rod(P, PRods, rod);
            end

            % IC photocurrent for all cones, cone photocurrent (either illuminated + noise or just noise)
            % Itc is the vector containing all time steps
            P.Itc = linspace(0,P.Tmax,ceil(P.Tmax/P.Tdelta)); % from t=0 to t=1s, 1000 points
            P.IC = zeros(nCones,ceil(P.Tmax/P.Tdelta)); % reset to 0
            for cone = 1:nCones
                P.IC(cone,:) = app.calcIphoto_cone(P, PCones, cone);
            end

            RCjc = P.RCjc; % store user settings for constant RCjc
            P.RCjc = zeros(nRPC); % RCjc rod/cone coupling conductance in S

            for i = 1:nRPC % choose from the following options:
                if P.RCjcRandom
                    % Random coupling for every rod (mean +/- sd)
                    a = P.RCjcRndSD; %standard deviation of normally distributed data
                    b = P.RCjcRndMean; %mean (S)
                    y = a.*randn(nRPC,1)+b;
                    P.RCjc(i,:)=(y);
                else
                    % Same coupling for all rods
                    for j = 1:nRPC
                        P.RCjc(j) = RCjc;
                    end
                end
            end

            % Initialize Gating variables (will get updated by myode method)
            app.nKv = zeros(nPR, 1); % Kv activation
            app.hKv = zeros(nPR, 1); % Kv inactivation (cones)
            app.nKx = zeros(nPR, 1); % Kx inactivation (rods)
            app.nh = zeros(nPR, 1);  % Ih activation
            app.nCa= zeros(nPR, 1);  % Calcium activation (cones)
            app.mCa= zeros(nPR, 1);  % Calcium activation (rods)
            app.hCa = zeros(nPR, 1); % Calcium inactivation
            app.dCai_dt = zeros(nPR, 1);
            app.Cai = zeros(nPR, 1); % Initial intracellular calcium concentration in mM

            % Initialize depending on photoreceptor type (cones vs rods)
            for pr = 1:nPR
                if pr<=nCones
                    app.mKv(pr) = 0.3709;  % Kv activation (cones)
                    app.hKv(pr) = 0.9998;  % Kv inactivation (cones)
                    app.nh(pr) =  0.0877;  % Ih activation (cones)
                    app.nCa(pr) = 0.0115;  % Calcium activation (cones)
                    app.Cai(pr) = 0.00001; % Intracellular calcium [] in mM (cones)
                else
                    app.nKv(pr) = 0.1518;  % Kv activation (rods)
                    app.nKx(pr) = 0.7735;  % Kx inactivation (rods)
                    app.nh(pr) =  0.0007;  % Ih activation (rods)
                    app.mCa(pr) = 0.0041;  % Calcium activation (rods)
                    app.hCa(pr) = 0.9975;  % Calcium inactivation (rods)
                    app.Cai(pr) = 0.00001; % Intracellular calcium [] in mM (rods)
                end
            end

            % Capacitance Matrix, storing the capacitance for each node
            app.C = zeros(nPR);
            for pr=1:nPR
                if pr<=nCones
                    app.C(pr,pr)=PCones.Cmc;  % Cone membrane capacitance;
                else
                    app.C(pr,pr)=PRods.Cmr; % Rod membrane capacitance;
                end
            end

            % Calculate conductance matrix graph;
            app.S_total = app.Cone_graph(P);            
        end
        
        function S = solveModel(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end            
            
            pb.Message = 'Initializing model...';                        
            S = struct;
            [S.Params, S.ParamsRods, S.ParamsCones] = app.initializeModel;
            S.ParamsTable = app.tblParams.Data;
            app.Params = S.Params;
            app.ParamsRods = S.ParamsRods;
            app.ParamsCones = S.ParamsCones;

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            if app.Params.RefCell > nPR
                msgbox("Selected reference cell does not exist","Error","error","modal");
                return
            end                        
            
            pb.Message = 'Solving differential equation, please wait...';
            pb.Indeterminate = 'off';
            pb.Value = 0;

            % Loop through each time to retrieve the voltage increment dVdt 
            dt = S.Params.Tdelta *1000;
            S.t = (0:S.Params.Tdelta:S.Params.Tmax)';
            S.Vis = zeros(numel(S.t), nPR, 'double');  % Voltage due to currents in the inner segment
            S.Vis(1,1:nCones) = S.ParamsCones.Ec;
            S.Vis(1,nCones+1:nPR) = S.ParamsRods.Er;
            S.Vgap = S.Vis; % Voltage due to current through gap junctions in the pedicle

            for i = 1:numel(S.t)-1
                if mod(S.t(i), S.Params.Tmax/100) == 0
                    pb.Value = i/numel(S.t); % Update bar evert 1 percent
                end
                [dVis_dt, dVgap_dt] = app.myode(S.t(i), S.Vis(i,:)');
                S.Vis(i+1,:) = S.Vis(i,:) + dVis_dt'*dt;
                S.Vgap(i+1,:) = S.Vgap(i,:) + dVgap_dt'*dt;
            end           

            % Combine voltage the voltage in gap junction with voltage in pedicle
            S.V = (S.Vis+S.Vgap)/2;
            %S.V = S.Vgap;
            pb.Indeterminate = 'off';
            pb.Message = 'Saving model data';
            save(['Model_' app.getCurrentDateString '.mat'], "S");

        end
        
        function [dVis_dt,dVgap_dt] = myode(app,t,V)        
            
            % Constants and Conversion factors
            CP = app.ParamsCones; % Parameters for cones
            RP = app.ParamsRods;  % Parameters for rods
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones; % Total number of photoreceptors            
            dt = app.Params.Tdelta * 1000; % delta T in ms 
            z = 2; % Valence of calcium ions
            fI = 10e-6; % Conversion factor for ICa % TODO Ask where this comes from?
            fF = 10e9; % Conversion factor for Faraday's constant
            F = 96485; % Faraday constant (C/mol)

            % Calculate dVdt for each photoreceptor (pr)
            I = zeros(nPR,1); % Resulting current in pA   
            for pr = 1:nPR
                if pr<=nCones
                    % Cone model with voltage-dependent conductances

                    % Update gating variables
                    amKv_cone =  (5 * (V(pr) - 100) ./ (1 - exp((100 - V(pr)) / 42)));
                    bmKv_cone =  (9 * exp((20 - V(pr)) / 40));
            
                    ahKv_cone =  (0.15 * exp(-V(pr) / 22));
                    bhKv_cone =  (0.4125 ./ (1 + exp((10 - V(pr)) / 7)));
            
                    anh_cone =  (0.001 * 18 ./ (1 + exp((V(pr) + 88) / 12)));
                    bnh_cone =  (0.001 * 18 ./ (1 + exp((-V(pr) - 18) / 19)));
            
                    anCa_cone =  (CP.aoCa * exp((V(pr) - CP.VhalfCa) / (2 * CP.SCa)));
                    bnCa_cone =  (CP.aoCa * exp((-V(pr) + CP.VhalfCa) / (2 * CP.SCa)));
            
                    hKCa_cone = app.Cai(pr) ./ (app.Cai(pr) + 0.3);
                    mCl_cone =  1 ./ (1 + exp(0.00037 - app.Cai(pr)) ./ 0.00009);

                    % Calculate individual currents
                    IKv_cone = CP.gKv_cone * app.mKv(pr)^3 * app.hKv(pr) * (V(pr) - CP.EKv_cone); % Kv current in pA
                    Ih_cone = CP.gh_cone * app.nh(pr) * (V(pr) - CP.Eh_cone); % Ih current in pA
                    ICa_cone = CP.gCa_cone * app.nCa(pr) * (V(pr) - CP.ECa_cone); % Calcium current in pA
                    IKCa_cone = CP.gKCa_cone * hKCa_cone * (V(pr) - CP.EKCa_cone); % K current (Ca dependent) in pA
                    ICl_cone = CP.gCl_cone * mCl_cone * (V(pr) - CP.ECl_cone); % Cl current (Ca dependent) in pA
                    IL_cone = CP.gL_cone * (V(pr) - CP.EL_cone); % Leak current in pA
                    Iphoto_cone = app.Params.IC(pr,floor(1+t*1000/dt));
                    
                    % Calculate total current
                    I(pr) = IKv_cone + Ih_cone + ICa_cone + IKCa_cone + ICl_cone + IL_cone + CP.Idark_cone + Iphoto_cone;

                    % Update channel gating variables
                    app.mKv(pr) = app.mKv(pr) + dt * (amKv_cone * (1 - app.mKv(pr)) - bmKv_cone * app.mKv(pr));
                    app.hKv(pr) = app.hKv(pr) + dt * (ahKv_cone * (1 - app.hKv(pr)) - bhKv_cone * app.hKv(pr));
                    app.nh(pr) = app.nh(pr) + dt * (anh_cone * (1 - app.nh(pr)) - bnh_cone * app.nh(pr));
                    app.nCa(pr) = app.nCa(pr) + dt * (anCa_cone * (1 - app.nCa(pr)) - bnCa_cone * app.nCa(pr));

                    % Update calcium concentration-
                    app.dCai_dt(pr) = -ICa_cone / (CP.volume_cone * z * F * fI * fF) - (app.Cai(pr) - CP.Cainf_cone) / CP.tau_Cai_cone;
                    app.Cai(pr) = app.Cai(pr) + dt * app.dCai_dt(pr);
                else
                    % Rod model with voltage-dependent conductances

                    % Update gating variables
                    anKv_rod = 0.005 * (20 - V(pr)) / (exp((20 - V(pr)) / 22) - 1);
                    bnKv_rod = 0.0625 * exp(-V(pr) / 80);

                    anKx_rod = 0.001 * 0.66 * exp((V(pr) + 50) / (2 * 5.7));
                    bnKx_rod = 0.001 * 0.66 * exp(-(V(pr) + 50) / (2 * 5.7));

                    anh_rod = 0.001 * exp((V(pr) + 75) / (-10.6));
                    bnh_rod = 0.001 * exp(-(V(pr) + 75) / (-10.6));

                    amCa_rod = 0.1 * exp((V(pr) + 10) / 12);
                    bmCa_rod = 0.1 * exp(-(V(pr) + 10) / 12);

                    ahCa_rod = 0.001 * 0.5 * exp(-(V(pr) - 11) / 18);
                    bhCa_rod = 0.001 * exp((V(pr) - 11) / 18);

                    nKCa_rod = 1 / (1 + (RP.K_KCa_rod / app.Cai(pr))^4);
                    nCl_rod = 1 / (1 + (RP.K_Cl_rod / app.Cai(pr))^4);

                    % Calculate individual currents
                    IKv_rod = RP.gKv_rod * app.nKv(pr)^4 * (V(pr) - RP.EKv_rod); % IKv current in pA
                    IKx_rod = RP.gKx_rod * app.nKx(pr) * (V(pr) - RP.EKx_rod); % IKx current in pA
                    Ih_rod = RP.gh_rod * app.nh(pr) * (V(pr) - RP.Eh_rod); %Ih current in pA
                    ICa_rod = RP.gCa_rod * app.mCa(pr) * app.hCa(pr) * (V(pr) - RP.ECa_rod); % Calcium current in pA
                    IKCa_rod = RP.gKCa_rod * nKCa_rod^4 * (V(pr) - RP.EKCa_rod); % IKCa current in pA
                    ICl_rod = RP.gCl_rod * nCl_rod * (V(pr) - RP.ECl_rod); % ICl current in pA
                    IL_rod = RP.gL_rod * (V(pr) - RP.EL_rod); % IL current in pA
                    Iphoto_rod = app.Params.IR(pr,floor(1+t*1000/dt));
                    
                    % Calculate total current
                    I(pr) = IKv_rod + IKx_rod + Ih_rod + ICa_rod + IKCa_rod + ICl_rod + IL_rod + RP.Idark_rod + Iphoto_rod;
                    
                    % Update channel gating variables
                    app.nKv(pr) = app.nKv(pr) + dt * (anKv_rod * (1 - app.nKv(pr)) - bnKv_rod * app.nKv(pr));
                    app.nKx(pr) = app.nKx(pr) + dt * (anKx_rod * (1 - app.nKx(pr)) - bnKx_rod * app.nKx(pr));
                    app.nh(pr) = app.nh(pr) + dt * (anh_rod * (1 - app.nh(pr)) -  bnh_rod * app.nh(pr));
                    app.mCa(pr) = app.mCa(pr) + dt * (amCa_rod * (1 - app.mCa(pr)) - bmCa_rod * app.mCa(pr));
                    app.hCa(pr) = app.hCa(pr) + dt * (ahCa_rod * (1 - app.hCa(pr)) - bhCa_rod * app.hCa(pr));

                    % Update Calcium concentration
                    app.dCai_dt(pr) = -ICa_rod / (RP.volume_rod * z * F * fI * fF) - (app.Cai(pr) - RP.Cainf_rod) / RP.tau_Cai_rod;
                    app.Cai(pr) = app.Cai(pr) + dt * app.dCai_dt(pr);
                end
            end

            % Old method, worked with ode15s solver but not with for-loop
            % Calculate dVdt = I/C where I(t) = I(t-1)-I(t) = S*V(t-1)-I(t); 
            %dVdt = (app.S_total*V-I)./diag(app.C);
            
            % Calculate dVis_dt = voltage step at the inner segments,
            % independent from gap junctions (separate compartments)
            dVis_dt = -I./diag(app.C);

            % Calculate dVgap_dt = voltage spreading through gap
            % junctionally connected neurons
            Igap = app.S_total*(dVis_dt*dt); 
            dVgap_dt = Igap./diag(app.C);
        end

        function I = calcIphoto_rod(~, P, PRods, rod)
            % Calculates photocurrent for the specified rod
            % using either the Single-photon response or Full light
            % response model.
            % P = current parameters for the model
            % rod = current rod number for which current is computed

            % Full light reponse model

            % Hill equation for Iamp
            n=1; % Hill coefficient
            Iamp_max = 30; % Maximal amplitude of photocurrent in pA
            R_half = 4; % Flash intensity at half-maximal amplitude
            R_star = P.FlashRstar; % Flash intensity in R*
            R_star = max(R_star, 1e-6); % Prevent log(0)
            Iamp = Iamp_max * (R_star^n / (R_star^n + R_half^n));

            % Time constants and c based on R_star
            if R_star <= 100000
                c = (366.05 * log(R_star / R_half) + 1347)/3;
                tau1 = (-5.263 * log(R_star / R_half) + 55.235)/3;
                tau2 = (20.392 * log(R_star / R_half) + 236.09)/3;
            end

            % Prepare constants needed for this code
            t_end = P.Tmax*1000;
            t_del = P.Tdelay*1000;
            dt = P.Tdelta*1000;
            num_time_steps = ceil(P.Tmax/P.Tdelta);
            time = linspace(0, t_end, num_time_steps);

            % Compute the original Iphoto
            I = Iamp .* ((1 - exp(-(time - t_del) / tau1))) .* (time > t_del) - ...
                Iamp .* (1 ./ (1 + exp(-(time - t_del - c) / tau2))) .* (time > t_del);

            % Preallocate noise array
            I_noise_photo = zeros(1, num_time_steps);

            sigma_noise = PRods.NoiseRodAmp;
            tau_noise = 1;

            % Generate noise for Iphoto
            for i = 1:num_time_steps - 1
                noise = sigma_noise * sqrt(2 / tau_noise) * randn() / sqrt(dt);
                I_noise_photo(i + 1) = I_noise_photo(i) + dt * (-I_noise_photo(i) / tau_noise + noise);
            end

            if P.Illuminated(rod)
                I(I < 0) = 0; % Clamp photocurrent to ensure it does not go below zero
                I = I + I_noise_photo; % Add Iphoto to noise
            else
                I = I_noise_photo; % keep only noise if not illuminated
            end
        end

        function I = calcIphoto_cone(~, P, PCones, cone)
            % Calculates photocurrent for the specified cone
            % using either the Single-photon response or Full light
            % response model.
            % P = current parameters for the model
            % cone = current cone number for which current is computed

            % Full light reponse model

            % Hill equation for Iamp
            n=1; % Hill coefficient
            Iamp_max = 30; % Maximal amplitude of photocurrent in pA
            R_half = 400; % Flash intensity at half-maximal amplitude
            R_star = P.FlashRstar; % Flash intensity in R*
            R_star = max(R_star, 1e-6); % Prevent log(0)
            Iamp = Iamp_max * (R_star^n / (R_star^n + R_half^n));

            % Time constants and c based on R_star
            if R_star < 40000
                c = (5.05 * log(R_star / R_half) + 300)/2;
                tau1 = (-5.263 * log(R_star / R_half) + 26.235)/2;
                tau2 = (2.392 * log(R_star / R_half) + 236.09)/3;
            end

            % Prepare constants needed for this code
            t_end = P.Tmax*1000;
            t_del = P.Tdelay*1000;
            dt = P.Tdelta*1000;
            num_time_steps = ceil(P.Tmax/P.Tdelta);
            time = linspace(0, t_end, num_time_steps);

            % Compute the original Iphoto
            I = Iamp .* ((1 - exp(-(time - t_del) / tau1))) .* (time > t_del) - ...
                Iamp .* (1 ./ (1 + exp(-(time - t_del - c) / tau2))) .* (time > t_del);

            % Preallocate noise array
            I_noise_photo = zeros(1, num_time_steps);

            sigma_noise = PCones.NoiseConeAmp;
            tau_noise = 1;

            % Generate noise for Iphoto
            for i = 1:num_time_steps - 1
                noise = sigma_noise * sqrt(2 / tau_noise) * randn() / sqrt(dt);
                I_noise_photo(i + 1) = I_noise_photo(i) + dt * (-I_noise_photo(i) / tau_noise + noise);
            end

            if P.Illuminated(cone)
                I(I < 0) = 0; % Clamp photocurrent to ensure it does not go below zero
                I = I + I_noise_photo; % Add Iphoto to noise
            else
                I = I_noise_photo; % keep only noise if not illuminated
            end
        end

        function S_total = Cone_graph(app, P)
            nCones = P.ConeRows*P.ConeCols; % Total number of cones
            nRPC = P.RodRows*P.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            S_Rod = app.Rod_Graph(P);

            % Cone connectivity matrix (each cone connected to the one at its right and to the one below)
            S_Cone = zeros(nCones,nCones);
            for cone = 1:nCones-1
                % Connect cones horizontally left-to-right (except last of each row)
                if mod(cone, P.ConeCols) > 0
                    S_Cone(cone,cone+1) = 1;
                end

               % Connect cones vertically top-to-bottom (except last of each column)
                if cone <= (nCones - P.ConeCols)  
                    S_Cone(cone, cone+P.ConeCols)  = 1;
                end
            end
            S_Cone(S_Cone==1) = P.CCjc; % Assign a CCjc all connected cones in S_Cone:


            % Compose S_Cone and S_Rod into a single block diagonal matrix
            S_total = blkdiag(S_Cone,S_Rod);

            % Connect each cone to blocks of rods
            for cone = 1:nCones
                for rod = 1:nRPC
                    S_total(cone,nCones + nRPC*(cone-1) +rod) = P.RCjc(rod);
                    S_total(nCones + nRPC*(cone-1) +rod, cone) = P.RCjc(rod);
                end
            end

            % Making diagonal elements by summing all conductances in a row.
            for i=1:nPR
                S_total(i,i)= sum(S_total(i,i:end))+sum(S_total(1:i,i));
            end
            %disp('test');

            % % Adding membrane conductance (1/Rm) to diagonal elements
            % for i = 1:nCones  %for cones
            %     S_total(i,i)= S_total(i,i)+(1/app.ParamsCones.Rmc);
            % end
            % 
            % for i = nCones+1:nPR  %for rods
            %     S_total(i,i)= S_total(i,i)+(1/app.ParamsRods.Rmr);
            % end
            
        end
        
        function S_Rod = Rod_Graph(~, P)
            % This function makes nCones blocks, each containing nRPC rods
            % S{cone} is the block of nRPC rods belonging to #cone
            % S{cone} is a nRPCxnRPC matrix encoding the condunctance of 
            %      Rod-Rod gap junctions
            %
            % S_Rod is a block diagonal matrix (nRPCxnCones,nRPCxnCones)
            %       where each element on the diagonal is S(cone}
            % 
            
            nCones = P.ConeRows*P.ConeCols; % Total number of cones
            nRPC = P.RodRows*P.RodCols;     % Total number of rods per cone
            S_Rod = zeros(nRPC*nCones, nRPC*nCones, 'double');

            % Define gap-junction connectivity within blocks of rods
            % Each cone is associated with a block of nRPC rods (arranged RodRowsxRodCols)
            for cone = 1:nCones
                cone_idx = nRPC*(cone-1)+1;
                for rod = 1:nRPC-1
                    % Horizontal gap junction resistance from RRjc
                    if mod(rod,P.RodCols) > 0
                        S_Rod(cone_idx+rod-1, cone_idx+rod) = P.RRjc;
                    end

                    % Vertical gap junction resistance from RRjc
                    if rod <= (nRPC-P.RodCols)
                        S_Rod(cone_idx+rod-1, cone_idx+rod+P.RodCols-1) = P.RRjc;
                    end
                end
            end

            % Define gap-junction connectivity between blocks of rods
            for cone = 1:nCones-1
                S_Rod_Pos = cone*nRPC;

                % connect horizontal matrix blocks from left to right (except last element of each row)
                if mod(cone,P.ConeCols) > 0
                    for rod = 1:P.RodRows
                        S_Rod((cone-1)*nRPC+rod*P.RodCols, S_Rod_Pos+rod*P.RodCols-P.RodCols+1) = P.Rblocksjc;
                    end
                end

                % connect vertical matrix blocks from top to bottom (except last element of each column)
                if cone <= (nCones - P.ConeCols)
                    for rod = nRPC-P.RodCols+1:nRPC
                        S_Rod((cone-1)*nRPC+rod, (cone+P.ConeCols-1)*nRPC+rod-P.RodCols) = P.Rblocksjc;
                    end                    
                end
            end            
        end
        
        function saveSolution(app, S)
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            % Saving the model output in CSV file
            filename = ['Model_' app.getCurrentDateString '_NodeVoltage.csv'];
            output = [S.t, S.V];
            rowsnum = nPR+1; % Number or rows in the output table
            V_Cell = cell(rowsnum,1);
            for i = 1:rowsnum
                if i==1
                    V_Cell{1,1}={'time'};
                elseif i>1 && i<=nCones+1
                    V_Cell{i,1}=sprintf('cone %d',i-1);
                elseif i>nCones+1 && i<=nPR+1
                    V_Cell{i,1}=sprintf('rod %d',i-1);
                end
            end
            T = table(V_Cell,output');
            writetable(T,filename)
        end
        
        function plotGraph(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end

            pb.Message = 'Initializing parameters...';                        
            S = struct;
            [S.Params, S.ParamsRods, S.ParamsCones] = app.initializeModel;
            S.ParamsTable = app.tblParams.Data;
            app.Params = S.Params;
            app.ParamsRods = S.ParamsRods;
            app.ParamsCones = S.ParamsCones;
            
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            RefCell = app.Params.RefCell;
            if RefCell<=nCones
                CellType = 'cone';                
            else
                CellType = 'rod';
            end

            if RefCell > nPR
                msgbox("Selected reference cell does not exist","Error","error","modal");
                return
            end

            app.Layout = tiledlayout(app.PnlPlots, 1, 1, "TileSpacing","tight","Padding","tight");
            
            % Plot the network graph
            pb.Message = 'Plotting connectivity graph...';
            ax = nexttile(app.Layout);

            % elements #1-nCones are cones, #nCones+1-nPR are rods
            cm = triu(app.S_total, 1); % Extract upper triangular part
            cm = cm + cm.'; % Add the transpose to make it symmetric
            G = graph(cm);
            p = plot(ax, G, 'Layout','force');

            % Set the z-coordinates for edges based on the connectivity matrix
            for i = 1:G.numnodes
                for j = i+1:G.numnodes
                    if cm(i, j) ~= 0
                        p.ZData([i j]) = [cm(i, j) cm(i, j)]; % Use edge lengths as z-coordinates
                    end
                end
            end            

            % Change color for markers based on cell identity
            Color_Cones = [1 0 0];
            Color_ConesIlluminated = [0.9843 0.5843 0.5451];
            Color_RefCell = [0 0.8 0];
            Color_Rods = [0 0.1 0.5];
            Color_RodsIlluminated = [0 0.5 1];
            
            node_colors = zeros(sum(p.ZData>=0), 3);
            node_colors(1:nCones, :) = repmat(Color_Cones, nCones, 1); % Set color of cones to red
            node_colors(nCones+1:end, :) = repmat(Color_Rods, nPR-nCones, 1); % Set color of rods to light blue
            
            % Identify which rods are illuminated and color them
            cones = find(app.Params.Illuminated);
            cones = cones(cones<=nCones);            
            for cone = 1:numel(cones)
                node_colors(cones(cone), :) = Color_ConesIlluminated;
            end
            rods = find(app.Params.Illuminated);
            rods = rods(rods>nCones);
            for rod = 1:numel(rods)
                node_colors(rods(rod), :) = Color_RodsIlluminated;
            end

            node_colors(RefCell, :) = Color_RefCell; % Set color of reference cone to orange
            p.NodeColor = node_colors;
            p.NodeLabel = 1:nPR; % Label each node as the cell number
            p.MarkerSize = 8; % Set the marker size as desired
            p.EdgeColor = [0.5 0.5 0.5]; % Set the connection lines to grey
            if app.Menu3Dplot.Checked
                view(ax, 30, 40); % Adjust azimuth and elevation angles as needed
            end
            % Set a custom legend for the connectivity plot
            hold(ax,"on")
            h = zeros(5, 1);
            h(1) = plot(ax,NaN,NaN, '.', 'Color', Color_Cones,   'MarkerSize',24);
            h(2) = plot(ax,NaN,NaN, '.', 'Color', Color_ConesIlluminated, 'MarkerSize',24);
            h(3) = plot(ax,NaN,NaN, '.', 'Color', Color_RefCell, 'MarkerSize',24);
            h(4) = plot(ax,NaN,NaN, '.', 'Color', Color_RodsIlluminated,    'MarkerSize',24);
            h(5) = plot(ax,NaN,NaN, '.', 'Color', Color_Rods,    'MarkerSize',24);
            legend(h, {'Cone','Cone Illuminated',['Reference ' CellType],'Rod Illuminated','Rod'}, 'Location', "northwest", "Box", "off");            
        end

        function plotResults(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            RefCell = app.Params.RefCell;
            if RefCell<=nCones
                CellType = 'cone';                
            else
                CellType = 'rod';
            end

            if RefCell > nPR
                msgbox("Selected reference cell does not exist","Error","error","modal");
                return
            end

            app.Layout = tiledlayout(app.PnlPlots, 5, 3, "TileSpacing","tight","Padding","tight");
            
            % Plot the network graph
            pb.Message = 'Connectivity graph';
            ax = nexttile(app.Layout, [2 3]);

            % elements #1-nCones are cones, #nCones+1-nPR are rods
            cm = triu(app.S_total, 1); % Extract upper triangular part
            cm = cm + cm.'; % Add the transpose to make it symmetric
            G = graph(cm);
            p = plot(ax, G, 'Layout','force');

            % Set the z-coordinates for edges based on the connectivity matrix
            for i = 1:G.numnodes
                for j = i+1:G.numnodes
                    if cm(i, j) ~= 0
                        p.ZData([i j]) = [cm(i, j) cm(i, j)]; % Use edge lengths as z-coordinates
                    end
                end
            end            

            % Change color for markers based on cell identity
            Color_Cones = [1 0 0];
            Color_ConesIlluminated = [0.9843 0.5843 0.5451];
            Color_RefCell = [0 0.8 0];
            Color_Rods = [0 0.1 0.5];
            Color_RodsIlluminated = [0 0.5 1];
            
            node_colors = zeros(sum(p.ZData>=0), 3);
            node_colors(1:nCones, :) = repmat(Color_Cones, nCones, 1); % Set color of cones to red
            node_colors(nCones+1:end, :) = repmat(Color_Rods, nPR-nCones, 1); % Set color of rods to light blue
            
            % Identify which rods are illuminated and color them
            cones = find(app.Params.Illuminated);
            cones = cones(cones<=nCones);            
            for cone = 1:numel(cones)
                node_colors(cones(cone), :) = Color_ConesIlluminated;
            end
            rods = find(app.Params.Illuminated);
            rods = rods(rods>nCones);
            for rod = 1:numel(rods)
                node_colors(rods(rod), :) = Color_RodsIlluminated;
            end

            node_colors(RefCell, :) = Color_RefCell; % Set color of reference cone to orange
            p.NodeColor = node_colors;
            p.NodeLabel = 1:nPR; % Label each node as the cell number
            p.MarkerSize = 8; % Set the marker size as desired
            p.EdgeColor = [0.5 0.5 0.5]; % Set the connection lines to grey
            if app.Menu3Dplot.Checked
                view(ax, 30, 40); % Adjust azimuth and elevation angles as needed
            end
            % Set a custom legend for the connectivity plot
            hold(ax,"on")
            h = zeros(5, 1);
            h(1) = plot(ax,NaN,NaN, '.', 'Color', Color_Cones,   'MarkerSize',24);
            h(2) = plot(ax,NaN,NaN, '.', 'Color', Color_ConesIlluminated, 'MarkerSize',24);
            h(3) = plot(ax,NaN,NaN, '.', 'Color', Color_RefCell, 'MarkerSize',24);
            h(4) = plot(ax,NaN,NaN, '.', 'Color', Color_RodsIlluminated,    'MarkerSize',24);
            h(5) = plot(ax,NaN,NaN, '.', 'Color', Color_Rods,    'MarkerSize',24);
            legend(h, {'Cone','Cone Illuminated',['Reference ' CellType],'Rod Illuminated','Rod'}, 'Location', "northwest", "Box", "off");
            
            % rod photocurrent
            pb.Message = 'Photocurrents of all rods';            
            ax = nexttile(app.Layout);
            plot(ax, app.Params.Itr,app.Params.IR);     
            title(ax, 'Photocurrent of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Current (pA)')
            %ylim(ax, [-4e-12 4e-12])
            xlim(ax, [0 app.Params.Tmax])
            grid(ax, "on")
            % Place an arrow on the flash trigger time (Tdelay)
            p1 = [app.Params.Tdelay -app.ParamsRods.NoiseRodAmp*5];  % First Point
            p2 = [app.Params.Tdelay -app.ParamsRods.NoiseRodAmp*9];   % Second Point
            dp = p2-p1; % Difference
            hold(ax,"on");
            quiver(ax, p1(1),p1(2),dp(1),dp(2),'b', ShowArrowHead = 'off', Marker = "^", MarkerFaceColor = [0 0 0])

            % Plot voltage of all rods
            pb.Message = 'Membrane potential of all rods';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=nCones+1:nPR
                plot(ax, app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, 'Vm  of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (mV)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax,"on")

            % Plot membrane voltage of the rods illuminated
            pb.Message = 'Voltage of the rods illuminated';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i = nCones+1:nPR
                if app.Params.Illuminated(i)
                    plot(ax, app.Solution.t, app.Solution.V(:,i));
                end
            end
            title(ax, ['Vm of illuminated rods (n=' num2str(sum(app.Params.Illuminated(nCones+1:nPR))) ')'])
            xlabel(ax, 'Time(s)')
            ylabel(ax, 'Voltage (mV)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax, "on")
            
            % cone photocurrent
            pb.Message = 'Photocurrent of all cones';            
            ax = nexttile(app.Layout);
            plot(ax, app.Params.Itc, app.Params.IC);
            title(ax, 'Photocurrent of all cones')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Current (pA)')
            xlim(ax, [0 app.Params.Tmax])
            %ylim(ax, [-4e-12 4e-12])
            grid(ax,"on")
            hold(ax,"on")
            % Place an arrow on the flash trigger time (Tdelay)
            p1 = [app.Params.Tdelay -app.ParamsRods.NoiseRodAmp*4];  % First Point
            p2 = [app.Params.Tdelay -app.ParamsRods.NoiseRodAmp*5];   % Second Point
            dp = p2-p1; % Difference
            hold(ax,"on");
            quiver(ax, p1(1),p1(2),dp(1),dp(2),'b', ShowArrowHead = 'off', Marker = "^", MarkerFaceColor = [0 0 0])
            

            % Plot membrane voltage of all cones
            pb.Message = 'Voltage of all cones';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=1:nCones
                plot(ax, app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, 'Vm  of all cones')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (mV)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax, "on")

            % Plot membrane voltage of illuminated cones
            pb.Message = 'Voltage of the rods illuminated';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i = 1:nCones
                if app.Params.Illuminated(i)
                    plot(ax, app.Solution.t, app.Solution.V(:,i));
                end
            end
            title(ax, ['Vm of illuminated cones (n=' num2str(sum(app.Params.Illuminated(1:nCones))) ')'])
            xlabel(ax, 'Time(s)')
            ylabel(ax, 'Voltage (mV)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax, "on")
            
            % Plot Vm of reference cone
            pb.Message = ['Voltage of reference ' CellType];            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            plot(ax, app.Solution.t,app.Solution.V(:,RefCell));
            title(ax, ['Vm of reference cell #' num2str(RefCell) ' (' CellType ')'])
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (mV)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax, "on")
            
            % Plot voltage of all rods coupled to reference cone
            pb.Message = ['Voltage of rods connected to reference ' CellType]; 
            ax = nexttile(app.Layout);
            hold(ax,"on")
            if RefCell<=nCones
                for i=(RefCell-1)*nRPC+nCones+1 : RefCell*nRPC+nCones
                    plot(ax,app.Solution.t, app.Solution.V(:,i));
                end
                title(ax, ['Vm of rods coupled to reference cell #' num2str(RefCell) '(' CellType ')'])
                xlabel(ax, 'Time(s)')
                ylabel(ax, 'Voltage (mV)')
                xlim(ax, [0 app.Params.Tmax])
                grid(ax, "on")
            end
            
            % Plot individual rods responses
            pb.Message = 'Voltage response amplitude of rods';            
            ax = nexttile(app.Layout);
            hold(ax,"on")             
            % Responses from all rods
            Vm = zeros(numel(nCones+1:nPR),3);
            counter = 0;
            for i = nCones+1:nPR 
                counter = counter+1; 
                Vm(counter,1) = i;
                Vm(counter,2) = min(app.Solution.V(:,i)) - app.Solution.V(1,i);
                Vm(counter,3) = ismember(i, (RefCell-1)*nRPC+nCones+1 : RefCell*nRPC+nCones);           
                scatter(ax, 1, Vm(counter,2),'jitter','on','jitterAmount',0.1);
            end
            
            % Responses from rods coupled to reference cone
            if RefCell<=nCones
                VmCoupled = zeros(numel(nCones+1:nPR),2);
                counter = 0;
                for i=(RefCell-1)*nRPC+nCones+1 : RefCell*nRPC+nCones
                    counter = counter+1;
                    VmCoupled(counter,1) = i;
                    VmCoupled(counter,2) = min(app.Solution.V(:,i)) - app.Solution.V(1,i);
                    scatter(ax, 2, VmCoupled(counter,2),'jitter','on','jitterAmount',0.1);
                end
            end
            ax.YDir = "reverse";
            title(ax, 'Voltage response amplitude of rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Response amplitude (mV)')
            xlim(ax, [0.75 2.5])
            grid(ax, "on")
            text(ax,1,mean(Vm(:,2)), ['All rods' newline 'mean ± sd' newline num2str(mean(Vm(:,2)),3) ' ± ' num2str(std(Vm(:,2)),3)] );
            if RefCell <= nCones
                text(ax,2,mean(Vm(:,2)), ['Coupled to' newline 'Ref. cone' newline 'mean ± sd' newline num2str(mean(VmCoupled(:,2)),3) ' ± ' num2str(std(VmCoupled(:,2)),3)] );
            end
            % Save values in a table the model output in Excel file
            filename = ['Model_' app.getCurrentDateString '_RodsResponseAmp.xlsx'];
            T = table;
            T.Rod = Vm(:,1);
            T.ResponseV = Vm(:,2);
            T.ConnectedToRefCell = Vm(:,3);
            writetable(T,filename)
            
            if app.MenuSavePlots.Checked
                pb.Message = 'Saving plots as PDF';
                exportgraphics(app.Layout, ['Model_' app.getCurrentDateString '_Plots.pdf']);

                pb.Message = 'Saving results spreadsheet';
                app.saveSolution(app.Solution);
            end
        end        

        function plotResultsAvg(app, pb, first_step, Series)            
            % Plots average traces for the reference neuron at the current step of a batch processing
            % first_step true if it is the first iteration in a series
            % Series contains metadata info about the series of plots

            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            RefCell = app.Params.RefCell;
            if RefCell<=nCones
                CellType = 'cone'; 
                if nCones == 1
                    Iphoto = app.Params.IC;
                else
                    Iphoto = app.Params.IC(RefCell, :);
                end
            else
                CellType = 'rod';
                if nPR-nCones == 1
                    Iphoto = app.Params.IR;
                else
                    Iphoto = app.Params.IR(RefCell-nCones, :);
                end
            end
            
            if RefCell > nPR
                msgbox("Selected reference cell does not exist","Error","error","modal");
                return
            end

            if first_step
                app.Layout = tiledlayout(app.PnlPlots, 5, 3, "TileSpacing","tight","Padding","tight");

                % Plot the network graph
                pb.Message = 'Connectivity graph';
                ax = nexttile(app.Layout, [2 3]);

                % elements #1-nCones are cones, #nCones+1-nPR are rods
                cm = triu(app.S_total, 1); % Extract upper triangular part
                cm = cm + cm.'; % Add the transpose to make it symmetric
                G = graph(cm);
                p = plot(ax, G, 'Layout','force');

                % Set the z-coordinates for edges based on the connectivity matrix
                for i = 1:G.numnodes
                    for j = i+1:G.numnodes
                        if cm(i, j) ~= 0
                            p.ZData([i j]) = [cm(i, j) cm(i, j)]; % Use edge lengths as z-coordinates
                        end
                    end
                end

                % Change color for markers based on cell identity
                Color_Cones = [1 0 0];
                Color_ConesIlluminated = [0.9843 0.5843 0.5451];
                Color_RefCell = [0 0.8 0];
                Color_Rods = [0 0.1 0.5];
                Color_RodsIlluminated = [0 0.5 1];

                node_colors = zeros(sum(p.ZData>=0), 3);
                node_colors(1:nCones, :) = repmat(Color_Cones, nCones, 1); % Set color of cones to red
                node_colors(nCones+1:end, :) = repmat(Color_Rods, nPR-nCones, 1); % Set color of rods to light blue

                % Identify which rods are illuminated and color them
                cones = find(app.Params.Illuminated);
                cones = cones(cones<=nCones);
                for cone = 1:numel(cones)
                    node_colors(cones(cone), :) = Color_ConesIlluminated;
                end
                rods = find(app.Params.Illuminated);
                rods = rods(rods>nCones);
                for rod = 1:numel(rods)
                    node_colors(rods(rod), :) = Color_RodsIlluminated;
                end

                node_colors(RefCell, :) = Color_RefCell; % Set color of reference cone to orange
                p.NodeColor = node_colors;
                p.NodeLabel = 1:nPR; % Label each node as the cell number
                p.MarkerSize = 8; % Set the marker size as desired
                p.EdgeColor = [0.5 0.5 0.5]; % Set the connection lines to grey
                if app.Menu3Dplot.Checked
                    view(ax, 30, 40); % Adjust azimuth and elevation angles as needed
                end
                % Set a custom legend for the connectivity plot
                hold(ax,"on")
                h = zeros(5, 1);
                h(1) = plot(ax,NaN,NaN, '.', 'Color', Color_Cones,   'MarkerSize',24);
                h(2) = plot(ax,NaN,NaN, '.', 'Color', Color_ConesIlluminated, 'MarkerSize',24);
                h(3) = plot(ax,NaN,NaN, '.', 'Color', Color_RefCell, 'MarkerSize',24);
                h(4) = plot(ax,NaN,NaN, '.', 'Color', Color_RodsIlluminated,    'MarkerSize',24);
                h(5) = plot(ax,NaN,NaN, '.', 'Color', Color_Rods,    'MarkerSize',24);
                legend(h, {'Cone','Cone Illuminated',['Reference ' CellType],'Rod Illuminated','Rod'}, 'Location', "northwest", "Box", "off");
            end

            % Plot Photocurrent of reference cell
            pb.Message = 'Photocurrent of reference cell';
            ax = nexttile(app.Layout,7, [1 2]);            
            plot(ax, app.Params.Itc,Iphoto);     
            title(ax, ['Reference cell #' num2str(RefCell) ' (' CellType '), ' Series.Name ' ranging from '...
                        num2str(Series.From) ' to ' num2str(Series.To) ' every ' num2str(Series.Stepping)])
            ylabel(ax, 'Current (pA)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax, "on")
            % Place an arrow on the flash trigger time (Tdelay)
            p1 = [app.Params.Tdelay -app.ParamsRods.NoiseRodAmp*5];  % First Point
            p2 = [app.Params.Tdelay -app.ParamsRods.NoiseRodAmp*9];   % Second Point
            dp = p2-p1; % Difference
            hold(ax,"on");
            quiver(ax, p1(1),p1(2),dp(1),dp(2),'b', ShowArrowHead = 'off', Marker = "^", MarkerFaceColor = [0 0 0])

            % Plot voltage of reference cell
            pb.Message = 'Membrane potential of reference cell';            
            ax = nexttile(app.Layout,10, [2 2]);
            plot(ax, app.Solution.t, app.Solution.V(:,RefCell));
            %title(ax, ['Vm of reference cell #' num2str(RefCell)])
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (mV)')
            xlim(ax, [0 app.Params.Tmax])
            grid(ax,"on")
            hold(ax,"on")

            % Plot current amplitude
            pb.Message = 'Voltage response of reference cell';            
            ax = nexttile(app.Layout, 9);
            Amp = max(Iphoto) - Iphoto(1);
            plot(ax, Series.Step, Amp, '-o');
            title(ax, ['Response amplitude of cell #' num2str(RefCell) ' (' CellType ')']);
            ylabel(ax, 'Current (pA)');
            xlim(ax, [Series.From-Series.Stepping/4 Series.To+Series.Stepping/4]);
            grid(ax, "on");
            hold(ax, "on");             

            % Plot voltage amplitude
            pb.Message = 'Voltage response amplitude of reference cell';            
            ax = nexttile(app.Layout, 12);
            Amp = min(app.Solution.V(:,RefCell)) - app.Solution.V(1,RefCell);
            plot(ax, Series.Step, Amp, '-o');
            ax.YDir = "reverse";
            ylabel(ax, 'Voltage (mV)');
            xlim(ax, [Series.From-Series.Stepping/4 Series.To+Series.Stepping/4]);
            grid(ax, "on");
            hold(ax, "on");             

            % Plot Gain (Voltage response / photocurrent)
            pb.Message = 'Gain of reference cell';            
            ax = nexttile(app.Layout, 15);
            AmpV = min(app.Solution.V(:,RefCell)) - app.Solution.V(1,RefCell);
            AmpI = max(Iphoto) - Iphoto(1);
            plot(ax, Series.Step, abs(AmpV/AmpI), '-o');
            xlabel(ax, Series.Name);
            ylabel(ax, 'Gain (mV/pA)');
            xlim(ax, [Series.From-Series.Stepping/4 Series.To+Series.Stepping/4]);
            grid(ax, "on");
            hold(ax, "on");             
            
            if app.MenuSavePlots.Checked
                pb.Message = 'Saving plots as PDF';
                exportgraphics(app.Layout, ['Model_' app.getCurrentDateString '_Plots.pdf']);

                pb.Message = 'Saving results spreadsheet';
                app.saveSolution(app.Solution);
            end
        end        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the dialog on main window
            posParent = get(0,'ScreenSize');
            pos = app.ConeRodModelUIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.ConeRodModelUIFigure.Position = pos;        

            app.ConeRodModelUIFigure.Name = [app.ConeRodModelUIFigure.Name ' ' app.Version];
            
            % Initialize table of parameters with default values
            P = app.getDefaultParams;
            t = table(fieldnames(P), struct2cell(P));
            app.tblParams.Data = t;

            PRods = app.getDefaultParamsRods;
            tR = table(fieldnames(PRods), struct2cell(PRods));
            app.tblParamsRods.Data = tR;

            PCones = app.getDefaultParamsCones;
            tC = table(fieldnames(PCones), struct2cell(PCones));
            app.tblParamsCones.Data = tC; 
            
            % Concatenate params for all tables and show them as batch settings
            tAll = [t;tR;tC];
            app.BatchSetting.Items = tAll{:,1};
            
            % Preview the default connectivity graph
            app.btnPreviewButtonPushed;
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Solving Model', 'Indeterminate','on');

            app.Solution = app.solveModel(pb);
            app.PlotButton.Enable = "on";
            close(pb);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            app.plotResults(pb);           
            close(pb);
        end

        % Button pushed function: BatchRemove
        function BatchRemoveButtonPushed(app, event)
            idx = app.BatchList.ValueIndex;
            app.BatchList.Items(idx) = [];
            app.BatchList.ItemsData(idx) = [];
            if isempty(app.BatchList.Items)
                app.BatchRemove.Enable = "off";
                app.BatchClear.Enable = "off";
                app.BatchRun.Enable = "off";
                app.BatchRunAvg.Enable = "off";
                app.BatchUpdate.Enable = "off";
            end
        end

        % Button pushed function: BatchClear
        function BatchClearButtonPushed(app, event)
            switch uiconfirm(app.ConeRodModelUIFigure, 'Remove all items from list?','Delete items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    app.BatchList.Items = {};
                    app.BatchList.ItemsData = [];
                    app.BatchRemove.Enable = "off";
                    app.BatchClear.Enable = "off";
                    app.BatchRun.Enable = "off";
                    app.BatchRunAvg.Enable = "off";
                    app.BatchUpdate.Enable = "off";
            end
        end

        % Button pushed function: BatchAdd
        function BatchAddButtonPushed(app, event)
            JobTitle = char(app.getCurrentDateString);
            L.P = app.tblParams.Data; % List of user-selected Settings
            L.PRods = app.tblParamsRods.Data; % List of user-selected Settings
            L.PCones = app.tblParamsCones.Data; % List of user-selected Settings
         
            if isempty(app.BatchList.Items)
                app.BatchList.Items = {JobTitle};
                app.BatchList.ItemsData = {L};
            else
                app.BatchList.Items{end+1} = JobTitle;
                app.BatchList.ItemsData(end+1) = {L};
            end

            app.BatchRemove.Enable = "on";
            app.BatchClear.Enable = "on";
            app.BatchRun.Enable = "on";
            app.BatchRunAvg.Enable = 'off';
        end

        % Double-clicked callback: BatchList
        function BatchListDoubleClicked(app, event)
            item = app.BatchList.Value;
            if ~isempty(item)
                app.tblParams.Data = item.P;
                app.tblParamsRods.Data = item.PRods;
                app.tblParamsCones.Data = item.PCones;
            end
        end

        % Button pushed function: BatchAddRange
        function BatchAddRangeButtonPushed(app, event)
            if ~app.BatchCustomRange.Value && (app.BatchFrom.Value >= app.BatchTo.Value)
                msgbox("Invalid range, please check the typed values.","Error","error");
                return
            end

            JobTitle = char(app.getCurrentDateString);
            L.P = app.tblParams.Data; % List of user-selected Settings
            L.PRods = app.tblParamsRods.Data; % List of user-selected Settings
            L.PCones = app.tblParamsCones.Data; % List of user-selected Settings

            S = app.BatchSetting.Value;
            if find(strcmp(S, L.P{:,1}),1) > 0
                Sidx = find(strcmp(S, L.P{:,1}),1);
                Sname = 'P';
            elseif find(strcmp(S, L.PRods{:,1}),1) > 0
                Sidx = find(strcmp(S, L.PRods{:,1}),1);
                Sname = 'PRods';
            elseif find(strcmp(S, L.PCones{:,1}),1) > 0
                Sidx = find(strcmp(S, L.PCones{:,1}),1);
                Sname = 'PCones';
            else
                msgbox("Cannot find selected property name in any table.","Error","error");
                return
            end           

            % Create several jobs for the range of values explored
            if app.BatchCustomRange.Value
                Steps = str2double(split(app.BatchCustomSteps.Value, ','))';
            else
                Steps = app.BatchFrom.Value:app.BatchSteps.Value:app.BatchTo.Value;
            end

            for V = Steps
                JobTitleStep = [JobTitle ' ' S '=' num2str(V)];
                Lstep = L;
                Lstep.(Sname){Sidx,2} = {V};
                Lstep.Series.Name = S;
                Lstep.Series.From = min(Steps);
                Lstep.Series.To = max(Steps);
                Lstep.Series.Stepping = Steps(2)-Steps(1);
                Lstep.Series.Step = V;

                if isempty(app.BatchList.Items)
                    app.BatchList.Items = {JobTitleStep};
                    app.BatchList.ItemsData = {Lstep};
                else
                    app.BatchList.Items{end+1} = JobTitleStep;
                    app.BatchList.ItemsData(end+1) = {Lstep};
                end
                
            end

            app.BatchRemove.Enable = "on";
            app.BatchClear.Enable = "on";
            app.BatchRun.Enable = "on"; 
            app.BatchRunAvg.Enable = "on";                        
        end

        % Button pushed function: BatchRun
        function BatchRunButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Batch processing:','Value', 0, 'Cancelable', 'on');

            for i = 1:numel(app.BatchList.Items)
                pb.Title = ['Batch processing: Model ' num2str(i) ' of ' num2str(numel(app.BatchList.Items))];
                pb.Value = i/numel(app.BatchList.Items);                
                app.BatchList.ValueIndex = i;
                pause(0.1) % Give time to the app to refresh

                L = app.BatchList.Value;
                app.tblParams.Data = L.P;
                app.tblParamsRods.Data = L.PRods;
                app.tblParamsCones.Data = L.PCones;
                
                app.Solution = app.solveModel(pb);
                app.plotResults(pb);
                pause(0.1); % Give time to the app to refresh

                if pb.CancelRequested
                    break;
                end                
            end

            close(pb);            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Supported formats (*.mat)'; '*.mat','Models (*.mat)'}, 'Select model', 'MultiSelect', 'off');
            
            app.ConeRodModelUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ConeRodModelUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if fName == 0
                return
            end
            
            % Read data from saved simulation
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Importing preset...','Indeterminate', 'on');
            pb.Message = ['Loading model from ' fName];
            Model = load(fullfile(PathName, fName),"S");
            app.Solution = Model.S;
            app.Params = Model.S.Params;
            app.ParamsRods = Model.S.ParamsRods;
            app.ParamsCones = Model.S.ParamsCones;            

            app.PlotButton.Enable = "on";
            close(pb);            
        end

        % Menu selected function: Menu3Dplot
        function Menu3DplotSelected(app, event)
            app.Menu3Dplot.Checked = ~app.Menu3Dplot.Checked;
        end

        % Button pushed function: btnInfo
        function btnInfoButtonPushed(app, event)
            Msg = {'\bf Cone-rod model parameters:';
                   '\rm ';
                   '\bf FlashRstar \rm Flash intensity (R*)';
                   '\bf RefCell \rm Reference cell (#)';
                   '\bf ConeRows \rm Rows of cones (#)';
                   '\bf ConeCols \rm Columns of cones (#)';
                   '\bf RodRows \rm Rows of rods (# per cone)';
                   '\bf RodCols \rm Columns of rods (# per cone)';
                   '\bf RodsIllNum \rm Illuminated rods (# per cone)';
                   '\bf ConesIllNum \rm Illuminated cones (#)';
                   '\bf RodsIllRandom \rm Randomize identity of illuminated rods'
                   '\bf ConesIllRandom \rm Randomize identity of illuminated cones'
                   '\bf Tmax \rm End time of the simulation (s)';
                   '\bf Tdelta \rm Sampling time (s)';                   
                   '\bf Tdelay \rm Delay of light flash trigger (s)';                   
                   '\bf CCjc \rm Cone-Cone coupling conductance (nS)';
                   '\bf RRjc \rm Rod-Rod coupling conductance (nS)';
                   '\bf RCjc \rm Rod-Cone coupling conductance (nS)';
                   '\bf RCjcRandom \rm Enables random conductances for RCjc';
                   '\bf RCjcRndMean \rm Mean of distribution to sample random RCjc (nS)';
                   '\bf RCjcRndSD \rm S.D. of distribution to sample random RCjc (nS)';
                   '\bf Rblocksjc \rm Coupling conductance between blocks of rods (nS) ';
                   '\bf NoiseRodAmp \rm Noise current amplitude for cones (pA)';
                   '\bf NoiseRodAmp \rm Noise current amplitude for rods (pA)';
                   '\rm ';
                   '\bf Contributors:'
                   '\rm Luca Della Santina (University of Houston)'
                   '\rm Christophe Ribelayga (University of Houston)'
                   '\rm Harshith Makkapati (University of Houston)'
                   '\rm ';
                   '\bf Licensed under GNU General Public License v3'
                   '\bf Copyright (C) 2024-2025 Luca Della Santina'
                   };

            Opts.Interpreter = 'tex';
            Opts.WindowStyle = 'non-modal';
            Opts.icon = 'help';

            msgbox(Msg, "Model parameters", Opts);
        end

        % Button pushed function: BatchUpdate
        function BatchUpdateButtonPushed(app, event)
            % Update selected batch item with current settings
            L.P = app.tblParams.Data; % List of user-selected Settings
            L.PRods = app.tblParamsRods.Data; % List of user-selected Settings
            L.PCones = app.tblParamsCones.Data; % List of user-selected Settings
            
            app.BatchList.ItemsData(app.BatchList.ValueIndex) = {L};
        end

        % Value changed function: BatchList
        function BatchListValueChanged(app, event)
            value = app.BatchList.Value;
            if ~isempty(value)
                app.BatchUpdate.Enable = "on";
            end
        end

        % Menu selected function: MenuSavePlots
        function MenuSavePlotsSelected(app, event)
            app.MenuSavePlots.Checked = ~app.MenuSavePlots.Checked;
        end

        % Button pushed function: BatchRunAvg
        function BatchRunAvgButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Batch processing:','Value', 0, 'Cancelable', 'on');

            for i = 1:numel(app.BatchList.Items)
                pb.Title = ['Batch processing: Model ' num2str(i) ' of ' num2str(numel(app.BatchList.Items))];
                pb.Value = i/numel(app.BatchList.Items);                
                app.BatchList.ValueIndex = i;
                pause(0.1) % Give time to the app to refresh

                L = app.BatchList.Value;
                app.tblParams.Data = L.P;
                app.tblParamsRods.Data = L.PRods;
                app.tblParamsCones.Data = L.PCones;
                
                app.Solution = app.solveModel(pb);
                app.plotResultsAvg(pb, i==1, L.Series);
                pause(0.1); % Give time to the app to refresh

                if pb.CancelRequested
                    break;
                end                
            end

            close(pb);                        
        end

        % Button pushed function: btnPreview
        function btnPreviewButtonPushed(app, event)
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            app.plotGraph(pb);           
            close(pb);            
        end

        % Value changed function: BatchCustomRange
        function BatchCustomRangeValueChanged(app, event)
            if app.BatchCustomRange.Value
                app.BatchCustomSteps.Enable = "on";
                app.BatchFrom.Enable = "off";
                app.BatchTo.Enable = "off";
                app.BatchSteps.Enable = "off";
            else
                app.BatchCustomSteps.Enable = "off";
                app.BatchFrom.Enable = "on";
                app.BatchTo.Enable = "on";
                app.BatchSteps.Enable = "on";                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConeRodModelUIFigure = uifigure('Visible', 'off');
            app.ConeRodModelUIFigure.Name = 'Cone-Rod Model';
            app.GridLayout = uigridlayout(app.ConeRodModelUIFigure);
            app.PnlPlots = uipanel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.SolveButton = uibutton(app.Panel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-solve.png');
            app.SolveButton.Tooltip = {'Solve differential equations of rod-cone connectivity'};
            app.SolveButton.Text = 'Solve';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-graph.png');
            app.PlotButton.Enable = 'off';
            app.PlotButton.Tooltip = {'Plot results of the model for cones and rods'};
            app.PlotButton.Text = 'Plot';
            app.BatchAdd = uibutton(app.Panel, 'push');
            app.BatchAdd.ButtonPushedFcn = createCallbackFcn(app, @BatchAddButtonPushed, true);
            app.BatchAdd.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-add.png');
            app.BatchAdd.Tooltip = {'Add current settings to batch list'};
            app.BatchAdd.Text = '';
            app.BatchRemove = uibutton(app.Panel, 'push');
            app.BatchRemove.ButtonPushedFcn = createCallbackFcn(app, @BatchRemoveButtonPushed, true);
            app.BatchRemove.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-remove.png');
            app.BatchRemove.Enable = 'off';
            app.BatchRemove.Tooltip = {'Remove selected batch item from list'};
            app.BatchRemove.Text = '';
            app.BatchClear = uibutton(app.Panel, 'push');
            app.BatchClear.ButtonPushedFcn = createCallbackFcn(app, @BatchClearButtonPushed, true);
            app.BatchClear.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-clear.png');
            app.BatchClear.Enable = 'off';
            app.BatchClear.Tooltip = {'Remove all batch items from list'};
            app.BatchClear.Text = '';
            app.BatchAddRange = uibutton(app.Panel, 'push');
            app.BatchAddRange.ButtonPushedFcn = createCallbackFcn(app, @BatchAddRangeButtonPushed, true);
            app.BatchAddRange.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-add-range.png');
            app.BatchAddRange.Tooltip = {'Create batch jobs for the specified setting and range'};
            app.BatchAddRange.Text = '';
            app.BatchList = uilistbox(app.Panel);
            app.BatchList.Items = {};
            app.BatchList.ValueChangedFcn = createCallbackFcn(app, @BatchListValueChanged, true);
            app.BatchList.Tooltip = {'List of batch processing jobs'};
            app.BatchList.DoubleClickedFcn = createCallbackFcn(app, @BatchListDoubleClicked, true);
            app.BatchList.Value = {};
            app.BatchRun = uibutton(app.Panel, 'push');
            app.BatchRun.ButtonPushedFcn = createCallbackFcn(app, @BatchRunButtonPushed, true);
            app.BatchRun.Icon = fullfile(pathToMLAPP, 'icons', '48px-robot.png');
            app.BatchRun.Enable = 'off';
            app.BatchRun.Tooltip = {'Run batch jobs from list and plot each individual model'};
            app.BatchRun.Text = {'Process'; 'Batch'};
            app.BatchSetting = uidropdown(app.Panel);
            app.BatchSetting.Items = {};
            app.BatchSetting.Tooltip = {'Parameter of the model to explore programmatically across the range specified below'};
            app.BatchSetting.Value = {};
            app.FromEditFieldLabel = uilabel(app.Panel);
            app.FromEditFieldLabel.Text = 'From';
            app.BatchFrom = uieditfield(app.Panel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.Panel);
            app.ToEditFieldLabel.Text = 'To';
            app.BatchTo = uieditfield(app.Panel, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.Panel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.BatchSteps = uieditfield(app.Panel, 'numeric');
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-open.png');
            app.LoadButton.Tooltip = {'Load a previously saved model'};
            app.LoadButton.Text = 'Load';
            app.btnInfo = uibutton(app.Panel, 'push');
            app.btnInfo.ButtonPushedFcn = createCallbackFcn(app, @btnInfoButtonPushed, true);
            app.btnInfo.Icon = fullfile(pathToMLAPP, 'icons', '48px-info.png');
            app.btnInfo.Tooltip = {'Information on the model''s parameters'};
            app.btnInfo.Text = '';
            app.BatchUpdate = uibutton(app.Panel, 'push');
            app.BatchUpdate.ButtonPushedFcn = createCallbackFcn(app, @BatchUpdateButtonPushed, true);
            app.BatchUpdate.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-refresh.png');
            app.BatchUpdate.Enable = 'off';
            app.BatchUpdate.Tooltip = {'Update selected batch item with current settings '};
            app.BatchUpdate.Text = '';
            app.TabGroup = uitabgroup(app.Panel);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.tblParams = uitable(app.SystemTab);
            app.tblParams.ColumnName = {'Parameter'; 'Value'};
            app.tblParams.RowName = {};
            app.tblParams.ColumnEditable = [false true];
            app.RodsTab = uitab(app.TabGroup);
            app.RodsTab.Title = 'Rods';
            app.tblParamsRods = uitable(app.RodsTab);
            app.tblParamsRods.ColumnName = {'Parameter'; 'Value'};
            app.tblParamsRods.RowName = {};
            app.tblParamsRods.ColumnEditable = [false true];
            app.ConesTab = uitab(app.TabGroup);
            app.ConesTab.Title = 'Cones';
            app.tblParamsCones = uitable(app.ConesTab);
            app.tblParamsCones.ColumnName = {'Parameter'; 'Value'};
            app.tblParamsCones.RowName = {};
            app.tblParamsCones.ColumnEditable = [false true];
            app.BatchRunAvg = uibutton(app.Panel, 'push');
            app.BatchRunAvg.ButtonPushedFcn = createCallbackFcn(app, @BatchRunAvgButtonPushed, true);
            app.BatchRunAvg.Icon = fullfile(pathToMLAPP, 'icons', '48px-robot_sum.png');
            app.BatchRunAvg.Enable = 'off';
            app.BatchRunAvg.Tooltip = {'Run batch jobs and plot response of reference neuron across models'};
            app.BatchRunAvg.Text = '';
            app.btnPreview = uibutton(app.Panel, 'push');
            app.btnPreview.ButtonPushedFcn = createCallbackFcn(app, @btnPreviewButtonPushed, true);
            app.btnPreview.Icon = fullfile(pathToMLAPP, 'icons', '48px-checklist.png');
            app.btnPreview.Tooltip = {'Preview connectivity graph'};
            app.btnPreview.Text = 'Check';
            app.BatchCustomRange = uicheckbox(app.Panel);
            app.BatchCustomRange.ValueChangedFcn = createCallbackFcn(app, @BatchCustomRangeValueChanged, true);
            app.BatchCustomRange.Text = 'Custom range';
            app.BatchCustomSteps = uieditfield(app.Panel, 'text');
            app.BatchCustomSteps.Enable = 'off';
            app.BatchCustomSteps.Tooltip = {'Specify individual steps separated by comma (e.g. 0,2,5,10,100,1000)'};
            app.ContextMenuPlots = uicontextmenu(app.ConeRodModelUIFigure);
            app.Menu3Dplot = uimenu(app.ContextMenuPlots);
            app.Menu3Dplot.MenuSelectedFcn = createCallbackFcn(app, @Menu3DplotSelected, true);
            app.Menu3Dplot.Checked = 'on';
            app.Menu3Dplot.Text = '3D Plot';
            app.MenuSavePlots = uimenu(app.ContextMenuPlots);
            app.MenuSavePlots.MenuSelectedFcn = createCallbackFcn(app, @MenuSavePlotsSelected, true);
            app.MenuSavePlots.Checked = 'on';
            app.MenuSavePlots.Text = 'Save Plots';
            % Assign app.ContextMenuPlots
            app.PnlPlots.ContextMenu = app.ContextMenuPlots;
            % Show the figure after all components are created
            app.ConeRodModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConeRodModel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConeRodModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ConeRodModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeRodModelUIFigure)
        end
    end
end